classdef Zeus_SWLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Zeus_SWUIFigure       matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        loadplxMenu           matlab.ui.container.Menu
        exportlogastxtMenu    matlab.ui.container.Menu
        DetectionMenu         matlab.ui.container.Menu
        modelsettingMenu      matlab.ui.container.Menu
        VideoMenu             matlab.ui.container.Menu
        videosettingMenu      matlab.ui.container.Menu
        StimulationMenu       matlab.ui.container.Menu
        stimsettingMenu       matlab.ui.container.Menu
        LogTextArea           matlab.ui.control.TextArea
        LogTextAreaLabel      matlab.ui.control.Label
        ControlPanel          matlab.ui.container.Panel
        StimSwitchLabel       matlab.ui.control.Label
        StimSwitch            matlab.ui.control.Switch
        VideoSwitchLabel      matlab.ui.control.Label
        VideoSwitch           matlab.ui.control.Switch
        DetectSwitchLabel     matlab.ui.control.Label
        DetectSwitch          matlab.ui.control.Switch
        StartButton           matlab.ui.control.StateButton
        BrowseButton          matlab.ui.control.Button
        ChannelInfoButton     matlab.ui.control.Button
        OpenFileButton        matlab.ui.control.StateButton
        PLXFileTextArea       matlab.ui.control.TextArea
        PLXFileTextAreaLabel  matlab.ui.control.Label
        ContextMenu           matlab.ui.container.ContextMenu
        ClearAllMenu          matlab.ui.container.Menu
        AddNewLogMenu         matlab.ui.container.Menu
        ExportLogMenu         matlab.ui.container.Menu
        ContextMenu2          matlab.ui.container.ContextMenu
        TicTocMenu            matlab.ui.container.Menu
    end


    properties (Access = private)
        plx_path = ''; %
        fid_plx = -1; % fopen(.plx)

        chan_info = []; % PL_SlowChannelHeader
        sel_chan = [];
        sel_id = [];
        uni_id = [];
        id_warn = [];

        ZeusTimer timer;
        MStart = 0;
        MEnd = 0;
        VideoTimer timer;
        StimTimer timer;

        detect = false;
        model_path = '';
        model_struct = [];
        model_setting = [1 1000 16 2, 60]; % [WindowSize SamplingRate ChanThresh WarnThresh CoolDown]
        cooldown = [];
        
        video = false;
        vid = []; % videoinput
        video_path = pwd;
        video_time = 60;
        
        stim = false;
        sdg = []; % visa
        stim_chan = 'C2';
        stim_time = 10;
        
        tictoc = false;
    end

    methods (Access = private)

        function UpdateMessage(app, new_message)
            app.LogTextArea.Value = [{['>', new_message]}; app.LogTextArea.Value];
            drawnow;
        end

        function SubmitInput(app, dlg, addtime, txt)
            t = txt.Value;
            if isempty(t) || all(cellfun(@(x) isempty(strtrim(x)), t))
                %%
            else
                text = strjoin(strtrim(t(:))', ' ');
                UpdateMessage(app, [addtime, '  ', text]);
            end
            delete(dlg);
        end

        function ZeusMonitor(app)
            if app.tictoc, t_zeus = tic; end

            if app.MStart == 0
                fseek(app.fid_plx, 0, 'eof');
                app.MStart = ftell(app.fid_plx);
            else
                fseek(app.fid_plx, 0, 'eof');
                app.MEnd = ftell(app.fid_plx);
                if (app.MEnd - app.MStart) <= 16
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  No More File Data To Be Read, Please Load A Recording File']);
                    stop(app.ZeusTimer);
                else
                    [data, nextpos] = sort_block_data(app.fid_plx, app.sel_chan, app.MStart, app.MEnd);
                    app.MStart = nextpos;
                    data = double(data);
                    if isempty(data)
                        UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                            '  Warning: Empty Data, Please Restart The Process']);
                        stop(app.ZeusTimer);
                    else
                        if app.detect
                            feats = [];
                            for ch = 1 : length(app.sel_chan)
                                feat = features_extract(data(:, ch), app.model_setting(2)); % model_setting.SamplingRate
                                feats = [feats; feat];
                            end
                            feats_sel = feats(:, app.model_struct.selected_features);
                            feats_sel_z = (feats_sel - app.model_struct.mu) ./ app.model_struct.sigma;
                            pred = predict(app.model_struct.model, feats_sel_z);
                            
                            for ui = 1 : numel(app.uni_id)
                                if app.cooldown(ui) < datetime('now')
                                    counts = sum(pred(app.sel_id == app.uni_id(ui)) == 1);
                                    if counts >= app.model_setting(3) % model_setting.ChanThresh
                                        app.id_warn(ui, :) = [app.id_warn(ui, 2:end), 1];
                                    else
                                        app.id_warn(ui, :) = [app.id_warn(ui, 2:end), 0];
                                    end
                                    
                                    if sum(app.id_warn(ui, :)) == app.model_setting(4) % model_setting.WarnThresh
                                        UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                                            '  *** SEIZURE ONSET *** ID: ', num2str(app.uni_id(ui))]);
                                        app.cooldown(ui) = datetime('now') + seconds(app.model_setting(5)); % model_setting.CoolDown
                                        app.id_warn(ui, :) = zeros(1, size(app.id_warn, 2));
                                        
                                        if app.video && ~isempty(app.vid) && isvalid(app.vid) && ~isrunning(app.vid)
                                            start(app.VideoTimer);
                                        end
                                        if app.stim && ~isempty(app.sdg) && strcmp(app.sdg.Status, 'open')
                                            start(app.StimTimer);
                                        end
                                    end
                                end
                            end                      
                        end                        
                    end                    
                end

            end

            if app.tictoc
                t = toc(t_zeus);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                    '  Last Loop Costed Time: ', num2str(t), 's', ...
                    ', data size: ', num2str(size(data, 1)), ' * ', num2str(size(data, 2))]);
                app.tictoc = false;
            end
        end
        
        function startVideo(app)
            try
                flushdata(app.vid);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Start Recording Video']);
                start(app.vid);
            catch ME
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  startVideoFcn: ', ME.message]);
            end
        end
        
        function stopVideo(app)
            try
                fname = fullfile(app.video_path, ['seizure_' datestr(now, 'yyyymmdd_HHMMSS') '.avi']);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Stop Recording Video']);
                stop(app.vid);
                framenum = app.vid.FramesAvailable;
                if framenum > 0
                    frames = getdata(app.vid, framenum);
                    vw = VideoWriter(fname, 'Motion JPEG AVI');
                    vw.FrameRate = 30;
                    open(vw);
                    writeVideo(vw, frames);
                    close(vw);
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Video Saved: ', fname]);
                end
                flushdata(app.vid);
            catch ME
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  stopVideoFcn: ', ME.message]);
            end
        end
        
        function startStimulation(app)
            try
                fprintf(app.sdg, [app.stim_chan, ':BSWV?']);
                set = fscanf(app.sdg);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Stimulation Setting: ', set]);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Start Light Stimulation']);
                fprintf(app.sdg, [app.stim_chan, ':OUTP ON']);
            catch ME
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  startStimFcn: ', ME.message]);
            end
        end
        
        function stopStimulation(app)
            try
                fprintf(app.sdg, [app.stim_chan, ':OUTP OFF']);
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  Stop Light Stimulation']);
            catch ME
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), '  stopStimFcn: ', ME.message]);
            end
        end
        
    end


    methods (Access = public)

        function setChanInfo(app, new_table)
            app.chan_info = new_table;
            app.sel_chan = new_table(new_table.Selected, :).Channel;
            app.sel_id = new_table(new_table.Selected, :).MouseID;
            
            app.uni_id = unique(app.sel_id, 'stable');
            app.id_warn = zeros(numel(app.uni_id), app.model_setting(4));
            app.cooldown = repmat(datetime, numel(app.uni_id), 1);
        end
        
        function setModelMenu(app, mpath, msetting)            
            if ~isempty(mpath) && ~strcmp(app.model_path, mpath)
                app.model_struct = importdata(mpath);
                app.DetectSwitch.Enable = 'on';
                app.DetectSwitch.Value = 'On';
                DetectSwitchValueChanged(app, []);
            end
            app.model_path = mpath;
            app.model_setting = msetting;
            
            app.ZeusTimer.Period = app.model_setting(1); % WindowSize
            app.id_warn = zeros(numel(app.uni_id), app.model_setting(4));
            app.cooldown = repmat(datetime, numel(app.uni_id), 1);
        end        
        
        function setVideoMenu(app, vpath, vformat, vlen)
            app.video_path = vpath;
            if ~isempty(app.vid) && isvalid(app.vid)
                stop(app.vid);
                delete(app.vid);
            end
            
            try
                app.vid = videoinput('winvideo', 1, vformat);
                triggerconfig(app.vid, 'immediate');
                app.vid.FramesPerTrigger = Inf;
                app.VideoSwitch.Enable = 'on';
            catch ME
                UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                    '  ', ME.message]);
                app.vid = [];
                app.VideoSwitch.Enable = 'off';
                app.VideoSwitch.Value = 'Off';
                app.video = false;
            end
            
            app.video_time = vlen;
            app.VideoTimer.StartDelay = app.video_time;
        end
        
        function setStimMenu(app, schan, stime)
            app.stim_chan = schan;
            app.stim_time = stime;
            app.StimTimer.StartDelay = app.stim_time;
            if strcmp(app.StimSwitch.Enable, 'off')
                app.StimSwitch.Enable = 'on';
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ZeusTimer = timer('ExecutionMode', 'fixedRate', ...
                'StartDelay', 0, ...
                'Period', app.model_setting(1), ...
                'TimerFcn', @(~, ~) ZeusMonitor(app), ...
                'BusyMode', 'drop');
            app.VideoTimer = timer('ExecutionMode', 'singleShot', ...
                'StartDelay', app.video_time, ...
                'StartFcn', @(~, ~) startVideo(app), ...
                'TimerFcn', @(~, ~) stopVideo(app));
            app.StimTimer = timer('ExecutionMode', 'singleShot', ...
                'StartDelay', app.stim_time, ...
                'StartFcn', @(~, ~) startStimulation(app), ...
                'TimerFcn', @(~, ~) stopStimulation(app));
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.plx', 'Plexon File(.plx)'}, 'Recording File Selector');
            if ~isequal(file, 0)
                app.plx_path = fullfile(path, file);
                app.PLXFileTextArea.Value = app.plx_path;
            end
        end

        % Value changed function: OpenFileButton
        function OpenFileButtonValueChanged(app, event)
            if app.OpenFileButton.Value
                if isempty(app.plx_path)
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  Open File Error: No Plx File Selected']);

                    app.OpenFileButton.Value = false;
                else
                    app.fid_plx = fopen(app.plx_path, 'rb', 'ieee-le');
                    if app.fid_plx < 0
                        UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                            '  Cannot Open ', app.plx_path]);
                    else
                        [~, ~, ~, shdr] = Read_PLX_Header(app.fid_plx);
                        thdr = struct2table(shdr);
                        mask = contains(thdr.Name, 'LFP') & thdr.Enabled == 1;
                        thdr = thdr(mask, {'Name', 'Channel'});
                        thdr.Selected = true(height(thdr), 1);
                        namestr = erase(thdr.Name, 'LFP');
                        namenum = str2double(namestr);
                        thdr.MouseID = ceil(namenum / 16);
                        app.chan_info = thdr;

                        UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                            '  Plx File Loaded Successfully: ', app.plx_path]);

                        app.OpenFileButton.Text = 'Close File';
                        app.BrowseButton.Enable = false;
                        app.loadplxMenu.Enable = false;

                        ChannelInfoButtonPushed(app, []);
                    end
                end
            else
                if ~isempty(fopen(app.fid_plx))
                    fclose(app.fid_plx);
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  Plx File Closed']);
                end

                app.fid_plx = -1;
                app.chan_info = [];
                app.sel_chan = [];
                app.sel_id = [];
                app.uni_id = [];
                app.id_warn = [];

                app.OpenFileButton.Text = 'Open File';
                app.BrowseButton.Enable = true;
                app.loadplxMenu.Enable = true;
            end
        end

        % Button pushed function: ChannelInfoButton
        function ChannelInfoButtonPushed(app, event)
            if ~isempty(app.chan_info)
                zeus_chan_select(app, app.chan_info);
            end
        end

        % Menu selected function: TicTocMenu
        function TicTocMenuSelected(app, event)
            app.tictoc = true;
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            choice = uiconfirm(app.Zeus_SWUIFigure, ...
                'Clear All Messages?', ...
                'Clear Confirm', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if strcmp(choice, 'Yes')
                app.LogTextArea.Value = {''};
            end
        end

        % Menu selected function: AddNewLogMenu
        function AddNewLogMenuSelected(app, event)
            addtime = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            dlg = uifigure('Name', 'Add User Message', ...
                'Position', [500 + randi([0, 100]), 500 + randi([0, 100]), 350, 150]);
            uilabel(dlg, 'Position', [20, 90, 300, 22], ...
                'Text', ['Add Message: ', addtime]);
            txt = uitextarea(dlg, 'Position', [20, 50, 310, 30]);
            uibutton(dlg, 'Text', 'Submit', ...
                'Position', [130, 10, 100, 30], ...
                'ButtonPushedFcn', @(btn, event) SubmitInput(app, dlg, addtime, txt));
        end

        % Menu selected function: ExportLogMenu, exportlogastxtMenu
        function ExportLogMenuSelected(app, event)
            [file, path] = uiputfile('log_*.txt', 'Save Log As');
            if isequal(file, 0)
                return;
            end
            fullpath = fullfile(path, file);

            fid_log = fopen(fullpath, 'w');
            if fid_log == -1
                uialert(app.Zeus_SWUIFigure, 'Can Not Open File', 'File Mistake');
                return;
            end
            reverselog = flip(app.LogTextArea.Value);
            for rl = 1 : length(reverselog)
                fprintf(fid_log, '%s\n', reverselog{rl});
            end
            fclose(fid_log);

            uialert(app.Zeus_SWUIFigure, 'Log Exported Successfully', 'Export Log');
        end

        % Menu selected function: loadplxMenu
        function loadplxMenuSelected(app, event)
            BrowseButtonPushed(app, []);
            app.OpenFileButton.Value = true;
            OpenFileButtonValueChanged(app, []);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value
                if ~isempty(fopen(app.fid_plx))
                    start(app.ZeusTimer);

                    app.modelsettingMenu.Enable = false;
                    app.videosettingMenu.Enable = false;
                    app.stimsettingMenu.Enable = false;
                    app.OpenFileButton.Enable = false;
                    app.ChannelInfoButton.Enable = false;
                    app.StartButton.Text = 'Stop';
                else
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  No Plx File Loaded']);
                    app.StartButton.Value = false;
                end
            else
                choice = uiconfirm(app.Zeus_SWUIFigure, ...
                    'Are You Sure to Stop Recording & Detecting?', ...
                    'Stop Confirm', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if strcmp(choice, 'Yes')
                    app.DetectSwitch.Value = 'Off';
                    app.VideoSwitch.Value = 'Off';
                    app.StimSwitch.Value = 'Off';
                    if isvalid(app.ZeusTimer) && strcmp(app.ZeusTimer.Running, 'on')
                        stop(app.ZeusTimer);
                    end

                    app.modelsettingMenu.Enable = true;
                    app.videosettingMenu.Enable = true;
                    app.stimsettingMenu.Enable = true;
                    app.OpenFileButton.Enable = true;
                    app.ChannelInfoButton.Enable = true;
                    app.StartButton.Text = 'Start';

                    app.MStart = 0;
                    app.id_warn = zeros(size(app.id_warn, 1), size(app.id_warn, 2));
                    app.cooldown = repmat(datetime, numel(app.uni_id), 1);
                else
                    app.StartButton.Value = true;
                end
            end

        end

        % Value changed function: DetectSwitch
        function DetectSwitchValueChanged(app, event)
            app.detect = strcmp(app.DetectSwitch.Value, 'On');
        end

        % Value changed function: VideoSwitch
        function VideoSwitchValueChanged(app, event)
            app.video = strcmp(app.VideoSwitch.Value, 'On');
        end

        % Value changed function: StimSwitch
        function StimSwitchValueChanged(app, event)
            app.stim = strcmp(app.StimSwitch.Value, 'On');
        end

        % Menu selected function: modelsettingMenu
        function modelsettingMenuSelected(app, event)
            zeus_model_setting(app, app.model_path, app.model_setting);
        end

        % Menu selected function: videosettingMenu
        function videosettingMenuSelected(app, event)
            zeus_video_setting(app, app.video_path, app.vid, app.video_time);
        end

        % Menu selected function: stimsettingMenu
        function stimsettingMenuSelected(app, event)
            if isempty(app.sdg)
                visalist = visadevlist;
                resname = visalist.ResourceName(find(contains(visalist.Model, 'SDG2122X'), 1));
                if isempty(resname)
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  No Device Connected']);
                else
                    app.sdg = visa('ni', resname);
                    fopen(app.sdg);
                    zeus_stim_setting(app, app.sdg, app.stim_chan, app.stim_time);
                end
            else
                try
                    if strcmp(app.sdg.Status, 'closed')
                        fopen(app.sdg);
                    end
                    fprintf(app.sdg, '*IDN?');
                    idn = fscanf(app.sdg);
                    if contains(idn, 'Siglent Technologies')
                        zeus_stim_setting(app, app.sdg, app.stim_chan, app.stim_time);
                    end
                catch ME
                    UpdateMessage(app, [datestr(now, 'yyyy-mm-dd HH:MM:SS'), ...
                        '  ', ME.message]);
                end
            end
        end

        % Close request function: Zeus_SWUIFigure
        function Zeus_SWUIFigureCloseRequest(app, event)
            exitconfirm = uiconfirm(app.Zeus_SWUIFigure, ...
                'Close the APP?', ...
                'Exit', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if strcmp(exitconfirm, 'Yes')
                try
                    if ~isempty(fopen(app.fid_plx))
                        fclose(app.fid_plx);
                    end
                    if isvalid(app.ZeusTimer)
                        if strcmp(app.ZeusTimer.Running, 'on')
                            stop(app.ZeusTimer);
                        end
                        delete(app.ZeusTimer);
                    end
                    if isvalid(app.VideoTimer)
                        if strcmp(app.VideoTimer.Running, 'on')
                            stop(app.VideoTimer);
                        end
                        delete(app.VideoTimer);
                    end
                    if isvalid(app.StimTimer)
                        if strcmp(app.StimTimer.Running, 'on')
                            stop(app.StimTimer);
                        end
                        delete(app.StimTimer);
                    end
                    if ~isempty(app.vid) && isvalid(app.vid)
                        stop(app.vid);
                        flushdata(app.vid);
                        delete(app.vid);
                    end
                    if ~isempty(app.sdg)
                        fclose(app.sdg);
                        delete(app.sdg);
                    end
                catch ME
                    disp(['Closing App Error: ', ME.message]);
                end
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Zeus_SWUIFigure = uifigure('Visible', 'off');
            app.Zeus_SWUIFigure.Name = 'Zeus_SW';
            app.Zeus_SWUIFigure.CloseRequestFcn = createCallbackFcn(app, @Zeus_SWUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.Zeus_SWUIFigure);
            app.FileMenu.Text = 'File';
            app.loadplxMenu = uimenu(app.FileMenu);
            app.loadplxMenu.MenuSelectedFcn = createCallbackFcn(app, @loadplxMenuSelected, true);
            app.loadplxMenu.Text = 'load .plx';
            app.exportlogastxtMenu = uimenu(app.FileMenu);
            app.exportlogastxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLogMenuSelected, true);
            app.exportlogastxtMenu.Text = 'export log as .txt';
            app.DetectionMenu = uimenu(app.Zeus_SWUIFigure);
            app.DetectionMenu.Text = 'Detection';
            app.modelsettingMenu = uimenu(app.DetectionMenu);
            app.modelsettingMenu.MenuSelectedFcn = createCallbackFcn(app, @modelsettingMenuSelected, true);
            app.modelsettingMenu.Text = 'model setting';
            app.VideoMenu = uimenu(app.Zeus_SWUIFigure);
            app.VideoMenu.Text = 'Video';
            app.videosettingMenu = uimenu(app.VideoMenu);
            app.videosettingMenu.MenuSelectedFcn = createCallbackFcn(app, @videosettingMenuSelected, true);
            app.videosettingMenu.Text = 'video setting';
            app.StimulationMenu = uimenu(app.Zeus_SWUIFigure);
            app.StimulationMenu.Text = 'Stimulation';
            app.stimsettingMenu = uimenu(app.StimulationMenu);
            app.stimsettingMenu.MenuSelectedFcn = createCallbackFcn(app, @stimsettingMenuSelected, true);
            app.stimsettingMenu.Text = 'stim setting';
            app.PLXFileTextAreaLabel = uilabel(app.Zeus_SWUIFigure);
            app.PLXFileTextAreaLabel.Text = 'PLX File';
            app.PLXFileTextArea = uitextarea(app.Zeus_SWUIFigure);
            app.PLXFileTextArea.Editable = 'off';
            app.OpenFileButton = uibutton(app.Zeus_SWUIFigure, 'state');
            app.OpenFileButton.ValueChangedFcn = createCallbackFcn(app, @OpenFileButtonValueChanged, true);
            app.OpenFileButton.Text = 'Open File';
            app.ChannelInfoButton = uibutton(app.Zeus_SWUIFigure, 'push');
            app.ChannelInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelInfoButtonPushed, true);
            app.ChannelInfoButton.Text = 'Channel Info';
            app.BrowseButton = uibutton(app.Zeus_SWUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ControlPanel = uipanel(app.Zeus_SWUIFigure);
            app.ControlPanel.Title = 'Control';
            app.StartButton = uibutton(app.ControlPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.DetectSwitch = uiswitch(app.ControlPanel, 'slider');
            app.DetectSwitch.ValueChangedFcn = createCallbackFcn(app, @DetectSwitchValueChanged, true);
            app.DetectSwitch.Enable = 'off';
            app.DetectSwitchLabel = uilabel(app.ControlPanel);
            app.DetectSwitchLabel.Enable = 'off';
            app.DetectSwitchLabel.Text = 'Detect';
            app.VideoSwitch = uiswitch(app.ControlPanel, 'slider');
            app.VideoSwitch.ValueChangedFcn = createCallbackFcn(app, @VideoSwitchValueChanged, true);
            app.VideoSwitch.Enable = 'off';
            app.VideoSwitchLabel = uilabel(app.ControlPanel);
            app.VideoSwitchLabel.Enable = 'off';
            app.VideoSwitchLabel.Text = 'Video';
            app.StimSwitch = uiswitch(app.ControlPanel, 'slider');
            app.StimSwitch.ValueChangedFcn = createCallbackFcn(app, @StimSwitchValueChanged, true);
            app.StimSwitch.Enable = 'off';
            app.StimSwitchLabel = uilabel(app.ControlPanel);
            app.StimSwitchLabel.Enable = 'off';
            app.StimSwitchLabel.Text = 'Stim';
            app.LogTextAreaLabel = uilabel(app.Zeus_SWUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.Zeus_SWUIFigure);
            app.LogTextArea.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.Zeus_SWUIFigure);
            app.ClearAllMenu = uimenu(app.ContextMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Text = 'Clear All';
            app.AddNewLogMenu = uimenu(app.ContextMenu);
            app.AddNewLogMenu.MenuSelectedFcn = createCallbackFcn(app, @AddNewLogMenuSelected, true);
            app.AddNewLogMenu.Text = 'Add New Log';
            app.ExportLogMenu = uimenu(app.ContextMenu);
            app.ExportLogMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLogMenuSelected, true);
            app.ExportLogMenu.Text = 'Export Log';
            % Assign app.ContextMenu
            app.LogTextArea.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.Zeus_SWUIFigure);
            app.TicTocMenu = uimenu(app.ContextMenu2);
            app.TicTocMenu.MenuSelectedFcn = createCallbackFcn(app, @TicTocMenuSelected, true);
            app.TicTocMenu.Text = 'Tic Toc';
            % Assign app.ContextMenu2
            app.ControlPanel.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.Zeus_SWUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zeus_SWLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Zeus_SWUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Zeus_SWUIFigure)
        end
    end
end