classdef zeus_model_setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelSettingUIFigure        matlab.ui.Figure
        SettingPanel                matlab.ui.container.Panel
        CoolDown_sEditField         matlab.ui.control.NumericEditField
        CoolDown_sEditFieldLabel    matlab.ui.control.Label
        WarnThreshEditField         matlab.ui.control.NumericEditField
        WarnThreshEditFieldLabel    matlab.ui.control.Label
        ChanThreshEditField         matlab.ui.control.NumericEditField
        ChanThreshEditFieldLabel    matlab.ui.control.Label
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        WindowSize_sEditField       matlab.ui.control.NumericEditField
        WindowSize_sEditFieldLabel  matlab.ui.control.Label
        BrowseButton                matlab.ui.control.Button
        ModelPathTextArea           matlab.ui.control.TextArea
        ModelPathTextAreaLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Zeus_SWLab
        m_path = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, path, setting)
            app.MainApp = mainapp;
            app.m_path = path;
            app.ModelPathTextArea.Value = path;
            app.WindowSize_sEditField.Value = setting(1);
            app.SamplingRateEditField.Value = setting(2);
            app.ChanThreshEditField.Value = setting(3);
            app.WarnThreshEditField.Value = setting(4);
            app.CoolDown_sEditField.Value = setting(5);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat', 'Model File(.mat)'}, 'Model File Selector');
            if ~isequal(file, 0)
                app.m_path = fullfile(path, file);
                app.ModelPathTextArea.Value = app.m_path;
            end
        end

        % Close request function: ModelSettingUIFigure
        function ModelSettingUIFigureCloseRequest(app, event)
            msetting = [app.WindowSize_sEditField.Value, ...
                app.SamplingRateEditField.Value, ...
                app.ChanThreshEditField.Value, ...
                app.WarnThreshEditField.Value, ...
                app.CoolDown_sEditField.Value];
            if isvalid(app.MainApp)
                app.MainApp.setModelMenu(app.m_path, msetting);
            end
            
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelSettingUIFigure = uifigure('Visible', 'off');
            app.ModelSettingUIFigure.Name = 'Model Setting';
            app.ModelSettingUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModelSettingUIFigureCloseRequest, true);
            app.ModelPathTextAreaLabel = uilabel(app.ModelSettingUIFigure);
            app.ModelPathTextAreaLabel.Text = 'Model Path';
            app.ModelPathTextArea = uitextarea(app.ModelSettingUIFigure);
            app.ModelPathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.ModelSettingUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SettingPanel = uipanel(app.ModelSettingUIFigure);
            app.SettingPanel.Title = 'Setting';
            app.WindowSize_sEditFieldLabel = uilabel(app.SettingPanel);
            app.WindowSize_sEditFieldLabel.Text = 'WindowSize_s';
            app.WindowSize_sEditField = uieditfield(app.SettingPanel, 'numeric');
            app.WindowSize_sEditField.Value = 1;
            app.SamplingRateEditFieldLabel = uilabel(app.SettingPanel);
            app.SamplingRateEditFieldLabel.Text = 'SamplingRate';
            app.SamplingRateEditField = uieditfield(app.SettingPanel, 'numeric');
            app.SamplingRateEditField.Editable = 'off';
            app.SamplingRateEditField.Value = 1000;
            app.ChanThreshEditFieldLabel = uilabel(app.SettingPanel);
            app.ChanThreshEditFieldLabel.Text = 'ChanThresh';
            app.ChanThreshEditField = uieditfield(app.SettingPanel, 'numeric');
            app.ChanThreshEditField.Value = 16;
            app.WarnThreshEditFieldLabel = uilabel(app.SettingPanel);
            app.WarnThreshEditFieldLabel.Text = 'WarnThresh';
            app.WarnThreshEditField = uieditfield(app.SettingPanel, 'numeric');
            app.WarnThreshEditField.Value = 2;
            app.CoolDown_sEditFieldLabel = uilabel(app.SettingPanel);
            app.CoolDown_sEditFieldLabel.Text = 'CoolDown_s';
            app.CoolDown_sEditField = uieditfield(app.SettingPanel, 'numeric');
            app.CoolDown_sEditField.Value = 60;
            % Show the figure after all components are created
            app.ModelSettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeus_model_setting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelSettingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelSettingUIFigure)
        end
    end
end