classdef zeus_stim_setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        OutPutChanDropDown       matlab.ui.control.DropDown
        OutPutChanDropDownLabel  matlab.ui.control.Label
        ParametersPanel          matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        Freq_hzEditField         matlab.ui.control.NumericEditField
        Freq_hzEditFieldLabel    matlab.ui.control.Label
        Time_sEditField          matlab.ui.control.NumericEditField
        Time_sEditFieldLabel     matlab.ui.control.Label
        Width_sEditField         matlab.ui.control.NumericEditField
        Width_sEditFieldLabel    matlab.ui.control.Label
        Offset_vEditField        matlab.ui.control.NumericEditField
        Offset_vEditFieldLabel   matlab.ui.control.Label
        Amp_vEditField           matlab.ui.control.NumericEditField
        Amp_vEditFieldLabel      matlab.ui.control.Label
        WaveTypeDropDown         matlab.ui.control.DropDown
        WaveTypeDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
        setting_sdg
    end
    
    methods (Access = private)
        
        function setPanel(app, responseparts)
            app.WaveTypeDropDown.Value = sdgseakpara(responseparts, 'WVTP');
            app.Freq_hzEditField.Value = sdgseakpara(responseparts, 'FRQ');
            app.Amp_vEditField.Value = sdgseakpara(responseparts, 'AMP');
            app.Offset_vEditField.Value = sdgseakpara(responseparts, 'OFST');
            app.Width_sEditField.Value = sdgseakpara(responseparts, 'WIDTH');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sdg, chan, time)
            app.MainApp = mainapp;
            app.setting_sdg = sdg;
            app.OutPutChanDropDown.Value = chan;
            
            fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV?']);
            response = fscanf(app.setting_sdg);
            resparts = split(response, ',');
            setPanel(app, resparts);
            
            app.Time_sEditField.Value = time;
        end

        % Value changed function: OutPutChanDropDown
        function OutPutChanDropDownValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV?']);
                response = fscanf(app.setting_sdg);
                resparts = split(response, ',');
                setPanel(app, resparts);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end
        end

        % Value changed function: WaveTypeDropDown
        function WaveTypeDropDownValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV WVTP,', ...
                    app.WaveTypeDropDown.Value]);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end         
        end

        % Value changed function: Freq_hzEditField
        function Freq_hzEditFieldValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV FRQ,', ...
                    num2str(app.Freq_hzEditField.Value)]);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end            
        end

        % Value changed function: Amp_vEditField
        function Amp_vEditFieldValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV AMP,', ...
                    num2str(app.Amp_vEditField.Value)]);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end
        end

        % Value changed function: Offset_vEditField
        function Offset_vEditFieldValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV OFST,', ...
                    num2str(app.Offset_vEditField.Value)]);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end
        end

        % Value changed function: Width_sEditField
        function Width_sEditFieldValueChanged(app, event)
            try
                fprintf(app.setting_sdg, [app.OutPutChanDropDown.Value, ':BSWV WIDTH,', ...
                    num2str(app.TextArea.Value)]);
                app.TextArea.Value = '';
            catch ME
                app.TextArea.Value = ME.message;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.setStimMenu(app.OutPutChanDropDown.Value, app.Time_sEditField.Value);
            end
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.WaveTypeDropDownLabel = uilabel(app.GridLayout);
            app.WaveTypeDropDownLabel.Text = 'WaveType';
            app.WaveTypeDropDown = uidropdown(app.GridLayout);
            app.WaveTypeDropDown.Items = {'SINE', 'SQUARE', 'RAMP', 'PULSE', 'NOISE', 'ARB', 'DC', 'PRBS', 'IQ'};
            app.WaveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveTypeDropDownValueChanged, true);
            app.WaveTypeDropDown.Value = 'PULSE';
            app.Amp_vEditFieldLabel = uilabel(app.GridLayout);
            app.Amp_vEditFieldLabel.Text = 'Amp_v';
            app.Amp_vEditField = uieditfield(app.GridLayout, 'numeric');
            app.Amp_vEditField.ValueChangedFcn = createCallbackFcn(app, @Amp_vEditFieldValueChanged, true);
            app.Offset_vEditFieldLabel = uilabel(app.GridLayout);
            app.Offset_vEditFieldLabel.Text = 'Offset_v';
            app.Offset_vEditField = uieditfield(app.GridLayout, 'numeric');
            app.Offset_vEditField.ValueChangedFcn = createCallbackFcn(app, @Offset_vEditFieldValueChanged, true);
            app.Width_sEditFieldLabel = uilabel(app.GridLayout);
            app.Width_sEditFieldLabel.Text = 'Width_s';
            app.Width_sEditField = uieditfield(app.GridLayout, 'numeric');
            app.Width_sEditField.ValueChangedFcn = createCallbackFcn(app, @Width_sEditFieldValueChanged, true);
            app.Time_sEditFieldLabel = uilabel(app.GridLayout);
            app.Time_sEditFieldLabel.Text = 'Time_s';
            app.Time_sEditField = uieditfield(app.GridLayout, 'numeric');
            app.Freq_hzEditFieldLabel = uilabel(app.GridLayout);
            app.Freq_hzEditFieldLabel.Text = 'Freq_hz';
            app.Freq_hzEditField = uieditfield(app.GridLayout, 'numeric');
            app.Freq_hzEditField.ValueChangedFcn = createCallbackFcn(app, @Freq_hzEditFieldValueChanged, true);
            app.OutPutChanDropDownLabel = uilabel(app.UIFigure);
            app.OutPutChanDropDownLabel.Text = 'OutPutChan';
            app.OutPutChanDropDown = uidropdown(app.UIFigure);
            app.OutPutChanDropDown.Items = {'C1', 'C2'};
            app.OutPutChanDropDown.ValueChangedFcn = createCallbackFcn(app, @OutPutChanDropDownValueChanged, true);
            app.OutPutChanDropDown.Value = 'C1';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeus_stim_setting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end