classdef zeus_video_setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoSettingUIFigure         matlab.ui.Figure
        VideoFormatDropDown          matlab.ui.control.DropDown
        VideoFormatLabel             matlab.ui.control.Label
        VideoLength_sEditField       matlab.ui.control.NumericEditField
        VideoLength_sEditFieldLabel  matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        VideoPathTextArea            matlab.ui.control.TextArea
        VideoPathTextAreaLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        MainApp % Zeus_SWLab
        v_path = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, vpath, vid, time)
            app.MainApp = mainapp;
            app.v_path = vpath;
            app.VideoPathTextArea.Value = vpath;
            
            info = imaqhwinfo('winvideo');
            deviceID = 1;
            vformats = info.DeviceInfo(deviceID).SupportedFormats;
            app.VideoFormatDropDown.Items = vformats;
            if ~isempty(vid) && isvalid(vid)
                app.VideoFormatDropDown.Value = vid.VideoFormat;
            end
            
            app.VideoLength_sEditField.Value = time;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir(pwd, 'Video Folder');
            if ~isequal(path, 0)
                app.v_path = path;
                app.VideoPathTextArea.Value = app.v_path;
            end
        end

        % Close request function: VideoSettingUIFigure
        function VideoSettingUIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.setVideoMenu(app.v_path, app.VideoFormatDropDown.Value, app.VideoLength_sEditField.Value);
            end
            
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoSettingUIFigure = uifigure('Visible', 'off');
            app.VideoSettingUIFigure.Name = 'Video Setting';
            app.VideoSettingUIFigure.CloseRequestFcn = createCallbackFcn(app, @VideoSettingUIFigureCloseRequest, true);
            app.VideoPathTextAreaLabel = uilabel(app.VideoSettingUIFigure);
            app.VideoPathTextAreaLabel.Text = 'Video Path';
            app.VideoPathTextArea = uitextarea(app.VideoSettingUIFigure);
            app.BrowseButton = uibutton(app.VideoSettingUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.VideoLength_sEditFieldLabel = uilabel(app.VideoSettingUIFigure);
            app.VideoLength_sEditFieldLabel.Text = 'Video Length_s';
            app.VideoLength_sEditField = uieditfield(app.VideoSettingUIFigure, 'numeric');
            app.VideoLength_sEditField.Value = 60;
            app.VideoFormatLabel = uilabel(app.VideoSettingUIFigure);
            app.VideoFormatLabel.Text = 'Video Format';
            app.VideoFormatDropDown = uidropdown(app.VideoSettingUIFigure);
            % Show the figure after all components are created
            app.VideoSettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeus_video_setting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoSettingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoSettingUIFigure)
        end
    end
end